classdef experimentInfoGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        frametimespath                  matlab.ui.control.EditField
        LoadFTButton                    matlab.ui.control.Button
        ExperimentDateDatePickerLabel   matlab.ui.control.Label
        ExperimentDateDatePicker        matlab.ui.control.DatePicker
        StimulusParametersButtonGroup   matlab.ui.container.ButtonGroup
        SameasExperimentPathButton      matlab.ui.control.RadioButton
        LoadfromTextFileButton          matlab.ui.control.RadioButton
        AutoButton                      matlab.ui.control.RadioButton
        SetupDropDownLabel              matlab.ui.control.Label
        SetupDropDown                   matlab.ui.control.DropDown
        ProjectorSwitchLabel            matlab.ui.control.Label
        ProjectorSwitch                 matlab.ui.control.ToggleSwitch
        ParametersTextFileLabel         matlab.ui.control.Label
        ParametersTextFileSwitch        matlab.ui.control.ToggleSwitch
        ArrayTypeDropDownLabel          matlab.ui.control.Label
        ArrayTypeDropDown               matlab.ui.control.DropDown
        StimulatorSwitch                matlab.ui.control.ToggleSwitch
        RefreshRateKnobLabel            matlab.ui.control.Label
        RefreshRateKnob                 matlab.ui.control.DiscreteKnob
        AnimalKnobLabel                 matlab.ui.control.Label
        AnimalKnob                      matlab.ui.control.DiscreteKnob
        SpikesorterSwitchLabel          matlab.ui.control.Label
        SpikesorterSwitch               matlab.ui.control.ToggleSwitch
        ExcelFileSwitchLabel            matlab.ui.control.Label
        ExcelFileSwitch                 matlab.ui.control.ToggleSwitch
        NumberofElectrodesLabel         matlab.ui.control.Label
        NumberofElectrodes              matlab.ui.control.NumericEditField
        ElectrodeDistanceLabel          matlab.ui.control.Label
        ElectrodeDistance               matlab.ui.control.NumericEditField
        ElectrodeSizLabel               matlab.ui.control.Label
        ElectrodeSize                   matlab.ui.control.NumericEditField
        kspath                          matlab.ui.control.EditField
        Experimentpath                  matlab.ui.control.EditField
        ExperimentpathEditField_3Label  matlab.ui.control.Label
        LoadKSButton                    matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        GenotypeLabel                   matlab.ui.control.Label
        Genotype                        matlab.ui.control.EditField
        AgeLabel                        matlab.ui.control.Label
        Age                             matlab.ui.control.EditField
        IDLabel                         matlab.ui.control.Label
        ID                              matlab.ui.control.EditField
        DateofBirthDatePickerLabel      matlab.ui.control.Label
        DateofBirthDatePicker           matlab.ui.control.DatePicker
        ExperimenterEditFieldLabel      matlab.ui.control.Label
        ExperimenterEF                  matlab.ui.control.EditField
        CalibrationDateDatePickerLabel  matlab.ui.control.Label
        CalibrationDateDatePicker       matlab.ui.control.DatePicker
        ProjectEditLabel                matlab.ui.control.Label
        Project                         matlab.ui.control.EditField
        Animalline                      matlab.ui.control.HTML
        EyeSwitch                       matlab.ui.control.ToggleSwitch
        LeftRightEyeSwitch              matlab.ui.control.ToggleSwitch
        RecordingRegionKnobLabel        matlab.ui.control.Label
        RecordingRegionKnob             matlab.ui.control.DiscreteKnob
        ArrayNameLabel                  matlab.ui.control.Label
        ArrayName                       matlab.ui.control.EditField
        TemperatureGauge                matlab.ui.control.LinearGauge
        FileTypeSwitchLabel             matlab.ui.control.Label
        FileTypeSwitch                  matlab.ui.control.ToggleSwitch
        AmplifierDropDownLabel          matlab.ui.control.Label
        AmplifierDropDown               matlab.ui.control.DropDown
        SamplingRateLabel               matlab.ui.control.Label
        SamplingRate                    matlab.ui.control.NumericEditField
        TemperatureLabel                matlab.ui.control.Label
        Temperature                     matlab.ui.control.NumericEditField
        SaveButton                      matlab.ui.control.Button
        ScreenWidthLabel                matlab.ui.control.Label
        ScreenWidth                     matlab.ui.control.NumericEditField
        ScreenHeightLabel               matlab.ui.control.Label
        ScreenHeight                    matlab.ui.control.NumericEditField
        PixelSizeLabel                  matlab.ui.control.Label
        PixelSizeEF                     matlab.ui.control.NumericEditField
        DelayLabel                      matlab.ui.control.Label
        DelayEF                         matlab.ui.control.NumericEditField
        MoreButton                      matlab.ui.control.Button
        ScreenLine                      matlab.ui.control.HTML
        ScreenLabel                     matlab.ui.control.Label
        FlowRateEditFieldLabel          matlab.ui.control.Label
        FlowRateEditField               matlab.ui.control.NumericEditField
        Recregionline                   matlab.ui.control.HTML
        BrightnessEditFieldLabel        matlab.ui.control.Label
        BrightnessEditField             matlab.ui.control.EditField
        StimulusListTextAreaLabel       matlab.ui.control.Label
        StimulusListTextArea            matlab.ui.control.TextArea
        CommentTextAreaLabel            matlab.ui.control.Label
        CommentTextArea                 matlab.ui.control.TextArea
        graybkg                         matlab.ui.control.TextArea
        BestScore                       matlab.ui.control.Spinner
        BestScoreLabel                  matlab.ui.control.Label
        WorstScoreLabel                 matlab.ui.control.Label
        WorstScore                      matlab.ui.control.Spinner
        SexLabel                        matlab.ui.control.Label
        Sex                             matlab.ui.control.DropDown
    end

    
    methods (Access = private)
        
        
        function getstimulinames(app,fnpath,endingtype)
            if strcmpi(endingtype,'mat')
                stimnames = dir([fnpath,filesep,'*_frametimings.',endingtype]);
                stimnames = extractBefore({stimnames.name}','_frametimings');
            else
                stimnames = dir([fnpath,filesep,'*.',endingtype]);
                stimnames = extractBefore({stimnames.name}',['*.',endingtype]);
            end
            app.UIFigure.UserData.stimulusnames = stimnames;
            ml = max(cellfun(@length,stimnames));
            stimnameswarped = cellfun(@(x)strcat(x,repmat('.',1,ml-length(x))),stimnames,'un',0);
            stimnameswarped = cellfun(@(x)([x(1:43),'...']),stimnameswarped,'un',0);
            stimnameswarped = strrep(stimnameswarped,'.','');
            app.StimulusListTextArea.Value = stimnameswarped;
            %app.StimulusListTextArea.wordwarp = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %testbuttonshape(app);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            p = uigetdir('D:\','Select Data path');
            app.UIFigure.UserData.datapath = p;
            app.Experimentpath.Value = p;
        end

        % Button pushed function: LoadKSButton
        function LoadKSButtonPushed(app, event)
            ksp = uigetdir('D:\','Select ks_sorted path');
            if isempty(app.UIFigure.UserData), dp = ksp; else, dp = app.UIFigure.UserData.datapath; end
            app.UIFigure.UserData.kspath = ksp;
            app.kspath.Value = ksp;
            ksras = [ksp,filesep,'ksrasters'];
            if ~exist(ksras,'dir') && ~exist([dp,filesep,'ksrasters'],'dir')
               ksques = uiconfirm(app.UIFigure,'Aint nobody found any ksrasters here! run readkilosortdata?','Read kilosort expinfoput');
               if ksques
                   readKilosortData(ksp);
               else
                   error('Da Fuck! analyzing without spike sorting!!!');
               end
            end
            
            if exist(ksras,'dir') && ~exist([dp,filesep,'ksrasters'],'dir')
                mkdir([dp,filesep,'ksrasters']);               
            end
            copyfile(ksras,[dp,filesep,'ksrasters']);
            
            frp = [fileparts(ksp),filesep,'frametimes'];
            if exist(frp,'dir')
                app.UIFigure.UserData.frametimespath = frp;
                app.frametimespath.Value = frp;
                app.LoadFTButton.Enable = 'off';
                getstimulinames(app,frp,'mat');
                if ~exist([dp,filesep,'frametimes'],'dir')
                    mkdir([dp,filesep,'frametimes']);
                end
                copyfile(frp,[dp,filesep,'frametimes']);
            end
            
        end

        % Button pushed function: LoadFTButton
        function LoadFTButtonPushed(app, event)
            frp = uigetdir('D:\','Select frametimes path');
            if isempty(app.UIFigure.UserData), dp = frp; else, dp = app.UIFigure.UserData.datapath; end
            app.UIFigure.UserData.frametimespath = frp;
            app.frametimespath.Value = frp;
            getstimulinames(app,frp,'mat');
            if ~exist([dp,filesep,'frametimes'],'dir')
               mkdir([dp,filesep,'frametimes']);
            end
            copyfile(frp,[dp,filesep,'frametimes']);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            expinfo.datapath = app.Experimentpath.Value;
            rawdatapath = [expinfo.datapath,filesep,'Data Analysis',filesep,'Raw Data'];
            if ~exist(rawdatapath,'dir'), mkdir(rawdatapath); end
            expinfo.kilosortpath = app.kspath.Value;
            expinfo.expdate = datestr(app.ExperimentDateDatePicker.Value);
            expinfo.project = app.Project.Value;
            expinfo.experimenter = app.ExperimenterEF.Value;
            expinfo.stimparatype = app.StimulusParametersButtonGroup.SelectedObject.Text;
            expinfo.samplingrate = app.SamplingRate.Value;
            expinfo.stimulusnames = app.UIFigure.UserData.stimulusnames;
            if isfield(app.UIFigure.UserData,'stimulipath')
            expinfo.stimuluspath = app.UIFigure.UserData.stimulipath;
            else
                StimParaBGSelectChanged(app, event);
                expinfo.stimuluspath = app.UIFigure.UserData.stimulipath;
            end
            % setup
            expinfo.setup.name = app.SetupDropDown.Value;
            expinfo.setup.amplifier = app.AmplifierDropDown.Value;
            expinfo.setup.samplingrate = app.SamplingRate.Value;
            expinfo.setup.temperature = app.Temperature.Value;
            expinfo.setup.flowrate = app.FlowRateEditField.Value;
            % screen
            expinfo.screen.type = app.ProjectorSwitch.Value;
            expinfo.screen.resolution = [app.ScreenWidth.Value, app.ScreenHeight.Value];
            expinfo.screen.refreshrate = str2double (app.RefreshRateKnob.Value);
            if strcmpi(app.StimulatorSwitch.Value,'color')
                expinfo.screen.color = true;
            else
                expinfo.screen.color = false;
            end
            expinfo.screen.pixelsize = app.PixelSizeEF.Value;
            expinfo.screen.delay = app.DelayEF.Value;
            expinfo.screen.calibrationdate = datestr(app.CalibrationDateDatePicker.Value);
            expinfo.screen.brightness = str2num(app.BrightnessEditField.Value); %#ok
            % animal
            expinfo.animal.species = app.AnimalKnob.Value;
            expinfo.animal.dateofbirth = datestr(app.DateofBirthDatePicker.Value);
            if strcmpi(app.AnimalKnob.Value,'marmoset')
                expinfo.animal.name = app.Genotype.Value;
                expinfo.animal.number = app.ID.Value;
            else
                expinfo.animal.genotype = app.Genotype.Value;
                expinfo.animal.id = app.ID.Value;
            end
            expinfo.animal.sex = app.Sex.Value;
            expinfo.animal.age = app.Age.Value;
            % array
            expinfo.array.type = app.ArrayTypeDropDown.Value;
            expinfo.array.numelectrodes = app.NumberofElectrodes.Value;
            expinfo.array.electrodedistance = app.ElectrodeDistance.Value;
            expinfo.array.electrodesize = app.ElectrodeSize.Value;
            expinfo.array.name = app.ArrayName.Value;
            % retina
            expinfo.retina.eyenumber = app.EyeSwitch.Value;
            expinfo.retina.eyeside = app.LeftRightEyeSwitch.Value;
            expinfo.retina.recordingregion = app.RecordingRegionKnob.Value;
            expinfo.options.parameterstxtfile = strcmpi(app.ParametersTextFileSwitch.Value,'yes');
            expinfo.options.excelfile = strcmpi(app.ExcelFileSwitch.Value,'yes');
            expinfo.spikesorting.sorter = app.SpikesorterSwitch.Value;
            expinfo.spikesorting.bestscore = app.BestScore.Value;
            expinfo.spikesorting.worstscore = app.WorstScore.Value;
            expinfo.spikesorting.datatype = app.FileTypeSwitch.Value;
            expinfo.comments = app.CommentTextArea.Value;
            app.SaveButton.UserData = expinfo;
            save([rawdatapath,filesep,'experiment inforamtion for data recorded on ',expinfo.expdate,'.mat'],'-v7.3','-struct','expinfo');
            disp('The experiment information is now saved, cabron');
            uiresume(app.UIFigure);
        end

        % Value changed function: ArrayTypeDropDown
        function ArrayTypeDropDownValueChanged(app, event)
            value = app.ArrayTypeDropDown.Value;
            switch value
            
                case '256MEA10030'
                    app.NumberofElectrodes.Value = 252;
                    app.ElectrodeDistance.Value = 100;
                    app.ElectrodeSize.Value = 30;
                    
                case '256MEA20030'
                    app.NumberofElectrodes.Value = 252;
                    app.ElectrodeDistance.Value = 200;
                    app.ElectrodeSize.Value = 30;
                    
                case '256MEA6010'
                    app.NumberofElectrodes.Value = 252;
                    app.ElectrodeDistance.Value = 60;
                    app.ElectrodeSize.Value = 10;
                    
                case '256MEA308'
                    app.NumberofElectrodes.Value = 252;
                    app.ElectrodeDistance.Value = 30;
                    app.ElectrodeSize.Value = 8;
                    
                case '60MEA10030'
                    app.NumberofElectrodes.Value = 60;
                    app.ElectrodeDistance.Value = 100;
                    app.ElectrodeSize.Value = 30;
                    
                case '60MEA20030'
                    app.NumberofElectrodes.Value = 60;
                    app.ElectrodeDistance.Value = 200;
                    app.ElectrodeSize.Value = 30;
                    
                case '60MEA6010'
                    app.NumberofElectrodes.Value = 60;
                    app.ElectrodeDistance.Value = 60;
                    app.ElectrodeSize.Value = 10;
                    
                case '60pMEA10030'
                    app.NumberofElectrodes.Value = 60;
                    app.ElectrodeDistance.Value = 100;
                    app.ElectrodeSize.Value = 30;
                    
                case '60pMEA20030'
                    app.NumberofElectrodes.Value = 60;
                    app.ElectrodeDistance.Value = 200;
                    app.ElectrodeSize.Value = 30;
                    
                case '4096CMOS4221'
                    app.NumberofElectrodes.Value = 4096;
                    app.ElectrodeDistance.Value = 42;
                    app.ElectrodeSize.Value = 21;
                    
                case 'Singlecell'
                    app.NumberofElectrodes.Value = 1;
                    app.ElectrodeDistance.Value = 0;
                    app.ElectrodeSize.Value = 0.001;
                    
                case 'Other'
                    app.NumberofElectrodes.Editable = true;
                    app.ElectrodeDistance.Editable = true;
                    app.ElectrodeSize.Editable = true;
                    
            end
        end

        % Value changed function: ProjectorSwitch
        function ProjectorSwitchValueChanged(app, event)
            value = app.ProjectorSwitch.Value;
            switch lower (value)
                case 'oled'
                    app.ScreenWidth.Value = 800;
                    app.ScreenHeight.Value = 600;
                    app.PixelSizeEF.Value = 7.5;
                    app.DelayEF.Value = 45;
                    app.SetupDropDown.Items = {'Aragorn','Bilbo','Elrond','Gandalf','Legolas','Bombadil','Sauroman','Troll'};
                    
                case 'lightcrafter'
                    app.SetupDropDown.Items = {'Aragorn','Bilbo'};
                    app.ScreenWidth.Value = 864;
                    app.ScreenHeight.Value = 480;
                    if strcmpi(app.SetupDropDown.Value, 'aragorn')
                        app.PixelSizeEF.Value = 7.2;
                    else
                        app.PixelSizeEF.Value = 7.6;
                    end
                    app.DelayEF.Value = 35;
                    app.StimulatorSwitch.Value = 'Color';
            end
        end

        % Selection changed function: StimulusParametersButtonGroup
        function StimParaBGSelectChanged(app, event)
            selectedButton = app.StimulusParametersButtonGroup.SelectedObject.Text;            
            switch lower(selectedButton)
                case 'same as experiment path'
                    app.UIFigure.UserData.stimulipath = [fileparts(app.kspath.Value),filesep,'stimuli'];
                case 'load from text file'
                    if isempty(app.UIFigure.UserData), p = 'D:/'; else, p = app.UIFigure.UserData.datapath; end
                    app.UIFigure.UserData.stimulipath = uigetdir(p,'Select stimuli path');
                case 'auto'
                    app.UIFigure.UserData.stimulipath = 'auto';
            end
            
            stimp = app.UIFigure.UserData.stimulipath;
            if isempty(app.UIFigure.UserData), dp = stimp; else, dp = app.UIFigure.UserData.datapath; end
            if exist(stimp,'dir')
                app.StimulusParametersButtonGroup.Tooltip = stimp;
                if ~exist([dp,filesep,'stimuli'],'dir')
                    mkdir([dp,filesep,'stimuli']);
                    copyfile(stimp,[dp,filesep,'stimuli']);
                end
            end
        end

        % Value changed function: AnimalKnob
        function AnimalKnobValueChanged(app, event)
            value = app.AnimalKnob.Value;
            switch lower(value)
                case 'marmoset'
                    app.RecordingRegionKnob.Items = {'Fovea','Periphery','Temporal','Nasal','Region number','Unknown'};
                    app.GenotypeLabel.Text = 'Name';
                    app.IDLabel.Text = 'Number';
                    app.MoreButton.Visible = 'on';
                    app.MoreButton.Enable = 'on';
                case {'mouse','axolotl'}
                    app.RecordingRegionKnob.Items = {'Ventral','Dorsal','Half','Whole mount','Random cut','Other'};
                    app.GenotypeLabel.Text = 'Genotype';
                    app.IDLabel.Text = 'ID';
                    app.MoreButton.Visible = 'off';
                    app.MoreButton.Enable = 'off';
            end
        end

        % Value changed function: Temperature
        function TemperatureValueChanged(app, event)
            app.TemperatureGauge.Value = app.Temperature.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.frametimespath = uieditfield(app.UIFigure, 'text');
            app.frametimespath.Value = 'Frametimes path';
            app.LoadFTButton = uibutton(app.UIFigure, 'push');
            app.LoadFTButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFTButtonPushed, true);
            app.LoadFTButton.Text = 'Load';
            app.ExperimentDateDatePickerLabel = uilabel(app.UIFigure);
            app.ExperimentDateDatePickerLabel.Text = 'Experiment Date';
            app.ExperimentDateDatePicker = uidatepicker(app.UIFigure);
            app.ExperimentDateDatePicker.DisabledDaysOfWeek = [1 7];
            app.StimulusParametersButtonGroup = uibuttongroup(app.UIFigure);
            app.StimulusParametersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StimParaBGSelectChanged, true);
            app.StimulusParametersButtonGroup.Title = 'Stimulus Parameters';
            app.SameasExperimentPathButton = uiradiobutton(app.StimulusParametersButtonGroup);
            app.SameasExperimentPathButton.Text = 'Same as Experiment Path';
            app.SameasExperimentPathButton.Value = true;
            app.LoadfromTextFileButton = uiradiobutton(app.StimulusParametersButtonGroup);
            app.LoadfromTextFileButton.Text = 'Load from Text File';
            app.AutoButton = uiradiobutton(app.StimulusParametersButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.SetupDropDownLabel = uilabel(app.UIFigure);
            app.SetupDropDownLabel.Text = 'Setup';
            app.SetupDropDown = uidropdown(app.UIFigure);
            app.SetupDropDown.Items = {'Aragorn', 'Bilbo', 'Elrond', 'Gandalf', 'Legolas', 'Bombadil', 'Sauroman', 'Troll'};
            app.SetupDropDown.Value = 'Aragorn';
            app.ProjectorSwitchLabel = uilabel(app.UIFigure);
            app.ProjectorSwitchLabel.Text = 'Projector';
            app.ProjectorSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ProjectorSwitch.Items = {'Lightcrafter', 'OLED'};
            app.ProjectorSwitch.ValueChangedFcn = createCallbackFcn(app, @ProjectorSwitchValueChanged, true);
            app.ProjectorSwitch.Value = 'Lightcrafter';
            app.ParametersTextFileLabel = uilabel(app.UIFigure);
            app.ParametersTextFileLabel.Text = {'Parameters '; 'Text File'};
            app.ParametersTextFileSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ParametersTextFileSwitch.Items = {'No', 'Yes'};
            app.ParametersTextFileSwitch.Value = 'No';
            app.ArrayTypeDropDownLabel = uilabel(app.UIFigure);
            app.ArrayTypeDropDownLabel.Text = 'Array Type';
            app.ArrayTypeDropDown = uidropdown(app.UIFigure);
            app.ArrayTypeDropDown.Items = {'256MEA10030', '256MEA20030', '256MEA6010', '256MEA308', '60MEA10030', '60MEA20030', '60MEA6010', '60pMEA10030', '60pMEA20030', '4096CMOS4221', 'Singlecell', 'Other'};
            app.ArrayTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ArrayTypeDropDownValueChanged, true);
            app.ArrayTypeDropDown.Value = '256MEA10030';
            app.StimulatorSwitch = uiswitch(app.UIFigure, 'toggle');
            app.StimulatorSwitch.Items = {'Color', 'Black & White'};
            app.StimulatorSwitch.Value = 'Color';
            app.RefreshRateKnobLabel = uilabel(app.UIFigure);
            app.RefreshRateKnobLabel.Text = 'Refresh Rate';
            app.RefreshRateKnob = uiknob(app.UIFigure, 'discrete');
            app.RefreshRateKnob.Items = {'60', '75', '85', 'other'};
            app.RefreshRateKnob.Value = '60';
            app.AnimalKnobLabel = uilabel(app.UIFigure);
            app.AnimalKnobLabel.Text = 'Animal';
            app.AnimalKnob = uiknob(app.UIFigure, 'discrete');
            app.AnimalKnob.Items = {'Mouse', 'Marmoset', 'Axolotl'};
            app.AnimalKnob.ValueChangedFcn = createCallbackFcn(app, @AnimalKnobValueChanged, true);
            app.AnimalKnob.Value = 'Mouse';
            app.SpikesorterSwitchLabel = uilabel(app.UIFigure);
            app.SpikesorterSwitchLabel.Text = 'Spike sorter';
            app.SpikesorterSwitch = uiswitch(app.UIFigure, 'toggle');
            app.SpikesorterSwitch.Items = {'Igor Pro', 'Kilosort'};
            app.SpikesorterSwitch.Value = 'Kilosort';
            app.ExcelFileSwitchLabel = uilabel(app.UIFigure);
            app.ExcelFileSwitchLabel.Text = 'Excel File';
            app.ExcelFileSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ExcelFileSwitch.Items = {'No', 'Yes'};
            app.ExcelFileSwitch.Value = 'No';
            app.NumberofElectrodesLabel = uilabel(app.UIFigure);
            app.NumberofElectrodesLabel.Text = 'Number of Electrodes';
            app.NumberofElectrodes = uieditfield(app.UIFigure, 'numeric');
            app.NumberofElectrodes.Limits = [0 10000];
            app.NumberofElectrodes.ValueDisplayFormat = '%.0f';
            app.NumberofElectrodes.Editable = 'off';
            app.NumberofElectrodes.Value = 252;
            app.ElectrodeDistanceLabel = uilabel(app.UIFigure);
            app.ElectrodeDistanceLabel.Text = 'Electrode Distance';
            app.ElectrodeDistance = uieditfield(app.UIFigure, 'numeric');
            app.ElectrodeDistance.Limits = [0 1000];
            app.ElectrodeDistance.ValueDisplayFormat = '%.0f';
            app.ElectrodeDistance.Editable = 'off';
            app.ElectrodeDistance.Value = 100;
            app.ElectrodeSizLabel = uilabel(app.UIFigure);
            app.ElectrodeSizLabel.Text = 'Electrode Size';
            app.ElectrodeSize = uieditfield(app.UIFigure, 'numeric');
            app.ElectrodeSize.Limits = [0 1000];
            app.ElectrodeSize.Editable = 'off';
            app.ElectrodeSize.Value = 30;
            app.kspath = uieditfield(app.UIFigure, 'text');
            app.kspath.Value = 'Kilosort path';
            app.Experimentpath = uieditfield(app.UIFigure, 'text');
            app.Experimentpath.Value = 'Experiment path';
            app.ExperimentpathEditField_3Label = uilabel(app.UIFigure);
            app.ExperimentpathEditField_3Label.Text = 'Experiment path';
            app.LoadKSButton = uibutton(app.UIFigure, 'push');
            app.LoadKSButton.ButtonPushedFcn = createCallbackFcn(app, @LoadKSButtonPushed, true);
            app.LoadKSButton.Text = 'Load';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.GenotypeLabel = uilabel(app.UIFigure);
            app.GenotypeLabel.Text = 'Genotype';
            app.Genotype = uieditfield(app.UIFigure, 'text');
            app.AgeLabel = uilabel(app.UIFigure);
            app.AgeLabel.Text = 'Age';
            app.Age = uieditfield(app.UIFigure, 'text');
            app.IDLabel = uilabel(app.UIFigure);
            app.IDLabel.Text = 'ID';
            app.ID = uieditfield(app.UIFigure, 'text');
            app.DateofBirthDatePickerLabel = uilabel(app.UIFigure);
            app.DateofBirthDatePickerLabel.Text = 'Date of Birth';
            app.DateofBirthDatePicker = uidatepicker(app.UIFigure);
            app.DateofBirthDatePicker.DisplayFormat = 'dd.MM.uuuu';
            app.ExperimenterEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.ExperimenterEF = uieditfield(app.UIFigure, 'text');
            app.CalibrationDateDatePickerLabel = uilabel(app.UIFigure);
            app.CalibrationDateDatePickerLabel.Text = 'Calibration Date';
            app.CalibrationDateDatePicker = uidatepicker(app.UIFigure);
            app.CalibrationDateDatePicker.DisplayFormat = 'dd.MM.uuuu';
            app.ProjectEditLabel = uilabel(app.UIFigure);
            app.ProjectEditLabel.Text = 'Project';
            app.Project = uieditfield(app.UIFigure, 'text');
            app.Animalline = uihtml(app.UIFigure);
            app.Animalline.HTMLSource = ' <svg height="10" width="390">  <line x1="0" y1="0" x2="390" y2="0" style="stroke:rgb(175,175,175);stroke-width:1" /></svg> ';
            app.EyeSwitch = uiswitch(app.UIFigure, 'toggle');
            app.EyeSwitch.Items = {'Second Eye', 'First Eye'};
            app.EyeSwitch.Value = 'First Eye';
            app.LeftRightEyeSwitch = uiswitch(app.UIFigure, 'toggle');
            app.LeftRightEyeSwitch.Items = {'Right Eye', 'Left Eye'};
            app.LeftRightEyeSwitch.Value = 'Left Eye';
            app.RecordingRegionKnobLabel = uilabel(app.UIFigure);
            app.RecordingRegionKnobLabel.Text = 'Recording Region';
            app.RecordingRegionKnob = uiknob(app.UIFigure, 'discrete');
            app.RecordingRegionKnob.Items = {'Ventral', 'Dorsal', 'Half', 'Whole mount', 'Random cut', 'Other'};
            app.RecordingRegionKnob.Value = 'Half';
            app.ArrayNameLabel = uilabel(app.UIFigure);
            app.ArrayNameLabel.Text = 'Array Name';
            app.ArrayName = uieditfield(app.UIFigure, 'text');
            app.TemperatureGauge = uigauge(app.UIFigure, 'linear');
            app.TemperatureGauge.Limits = [20 40];
            app.TemperatureGauge.Value = 33;
            app.FileTypeSwitchLabel = uilabel(app.UIFigure);
            app.FileTypeSwitchLabel.Text = 'File Type';
            app.FileTypeSwitch = uiswitch(app.UIFigure, 'toggle');
            app.FileTypeSwitch.Items = {'MSRD', 'MCD'};
            app.FileTypeSwitch.Value = 'MCD';
            app.AmplifierDropDownLabel = uilabel(app.UIFigure);
            app.AmplifierDropDownLabel.Text = 'Amplifier';
            app.AmplifierDropDown = uidropdown(app.UIFigure);
            app.AmplifierDropDown.Items = {'Mcs-MEA', 'Mcs-pMEA', '3BrainCMOS', 'Patch Single Cell', 'Sharp Electrode'};
            app.AmplifierDropDown.Tooltip = {'Select the amplifier type'};
            app.AmplifierDropDown.Value = 'Mcs-MEA';
            app.SamplingRateLabel = uilabel(app.UIFigure);
            app.SamplingRateLabel.Text = 'Sampling Rate';
            app.SamplingRate = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRate.Limits = [5000 50000];
            app.SamplingRate.ValueDisplayFormat = '%.0f';
            app.SamplingRate.Tooltip = {'Hz'};
            app.SamplingRate.Value = 25000;
            app.TemperatureLabel = uilabel(app.UIFigure);
            app.TemperatureLabel.Text = 'Temperature';
            app.Temperature = uieditfield(app.UIFigure, 'numeric');
            app.Temperature.Limits = [20 45];
            app.Temperature.ValueDisplayFormat = '%.0f';
            app.Temperature.ValueChangedFcn = createCallbackFcn(app, @TemperatureValueChanged, true);
            app.Temperature.Tooltip = {'°C'};
            app.Temperature.Value = 33;
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ScreenWidthLabel = uilabel(app.UIFigure);
            app.ScreenWidthLabel.Text = 'Screen Width';
            app.ScreenWidth = uieditfield(app.UIFigure, 'numeric');
            app.ScreenWidth.Limits = [1 8000];
            app.ScreenWidth.ValueDisplayFormat = '%.0f';
            app.ScreenWidth.Value = 864;
            app.ScreenHeightLabel = uilabel(app.UIFigure);
            app.ScreenHeightLabel.Text = 'Screen Height';
            app.ScreenHeight = uieditfield(app.UIFigure, 'numeric');
            app.ScreenHeight.Limits = [1 5000];
            app.ScreenHeight.ValueDisplayFormat = '%.0f';
            app.ScreenHeight.Value = 480;
            app.PixelSizeLabel = uilabel(app.UIFigure);
            app.PixelSizeLabel.Text = 'Pixel Size';
            app.PixelSizeEF = uieditfield(app.UIFigure, 'numeric');
            app.PixelSizeEF.Limits = [0 1000];
            app.PixelSizeEF.Value = 7.2;
            app.DelayLabel = uilabel(app.UIFigure);
            app.DelayLabel.Text = 'Delay';
            app.DelayEF = uieditfield(app.UIFigure, 'numeric');
            app.DelayEF.Limits = [0 1000];
            app.DelayEF.Value = 35;
            app.MoreButton = uibutton(app.UIFigure, 'push');
            app.MoreButton.Enable = 'off';
            app.MoreButton.Visible = 'off';
            app.MoreButton.Text = 'More';
            app.ScreenLine = uihtml(app.UIFigure);
            app.ScreenLine.HTMLSource = ' <svg height="10" width="420">  <line x1="0" y1="0" x2="420" y2="0" style="stroke:rgb(175,175,175);stroke-width:1" /></svg> ';
            app.ScreenLabel = uilabel(app.UIFigure);
            app.ScreenLabel.Text = 'Screen';
            app.FlowRateEditFieldLabel = uilabel(app.UIFigure);
            app.FlowRateEditFieldLabel.Text = 'Flow Rate';
            app.FlowRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlowRateEditField.Limits = [0 1000];
            app.FlowRateEditField.Tooltip = {'ml/sec'};
            app.FlowRateEditField.Value = 5;
            app.Recregionline = uihtml(app.UIFigure);
            app.Recregionline.HTMLSource = ' <svg height="10" width="420">  <line x1="0" y1="0" x2="420" y2="0" style="stroke:rgb(175,175,175);stroke-width:1" /></svg> ';
            app.BrightnessEditFieldLabel = uilabel(app.UIFigure);
            app.BrightnessEditFieldLabel.Text = 'Brightness';
            app.BrightnessEditField = uieditfield(app.UIFigure, 'text');
            app.BrightnessEditField.Value = '80';
            app.StimulusListTextAreaLabel = uilabel(app.UIFigure);
            app.StimulusListTextAreaLabel.Text = 'Stimulus List';
            app.StimulusListTextArea = uitextarea(app.UIFigure);
            app.StimulusListTextArea.Editable = 'off';
            app.CommentTextAreaLabel = uilabel(app.UIFigure);
            app.CommentTextAreaLabel.Text = 'Comment';
            app.CommentTextArea = uitextarea(app.UIFigure);
            app.graybkg = uitextarea(app.UIFigure);
            app.graybkg.Editable = 'off';
            app.graybkg.Enable = 'off';
            app.BestScore = uispinner(app.UIFigure);
            app.BestScore.Limits = [1 25];
            app.BestScore.ValueDisplayFormat = '%.0f';
            app.BestScore.Value = 1;
            app.BestScoreLabel = uilabel(app.UIFigure);
            app.BestScoreLabel.Text = 'Best Score';
            app.WorstScoreLabel = uilabel(app.UIFigure);
            app.WorstScoreLabel.Text = 'Worst Score';
            app.WorstScore = uispinner(app.UIFigure);
            app.WorstScore.Limits = [1 25];
            app.WorstScore.ValueDisplayFormat = '%.0f';
            app.WorstScore.Value = 4;
            app.SexLabel = uilabel(app.UIFigure);
            app.SexLabel.Text = 'Sex';
            app.Sex = uidropdown(app.UIFigure);
            app.Sex.Items = {'Female', 'Male', 'Nonbinary', 'Unknown'};
            app.Sex.Value = 'Female';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = experimentInfoGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end